classdef V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainMenuTab                    matlab.ui.container.Tab
        Hyperlink                      matlab.ui.control.Hyperlink
        PlotButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        PleaseInputyourweightandLoadthesessionFileThankYouLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        msLabel                        matlab.ui.control.Label
        NLabel                         matlab.ui.control.Label
        lbsLabel                       matlab.ui.control.Label
        WeightEditField                matlab.ui.control.NumericEditField
        WeightEditField_2Label         matlab.ui.control.Label
        VelocityonImpactEditField      matlab.ui.control.NumericEditField
        VelocityonImpactEditField_2Label  matlab.ui.control.Label
        ForceonImpactEditField         matlab.ui.control.NumericEditField
        ForceonImpactEditField_2Label  matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        DPlotsTab_2                    matlab.ui.container.Tab
        AngVelAxes                     matlab.ui.control.UIAxes
        VelocityAxes                   matlab.ui.control.UIAxes
        ForceAxes                      matlab.ui.control.UIAxes
        AccelerationAxes               matlab.ui.control.UIAxes
        Table                          matlab.ui.container.Tab
        DIdealLegDisplacementTab       matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        StatisticalAnalysesandFeedbackTab  matlab.ui.container.Tab
        IdealKickTutorial1ApproachingTab  matlab.ui.container.Tab
        Step5LookattheballbeforehittingitLabel  matlab.ui.control.Label
        Step4PointyourfootwhereyouwanttheballtogoLabel  matlab.ui.control.Label
        Image7                         matlab.ui.control.Image
        Step3PlaceyourotherfootclosetotheballLabel  matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        Step2TakeBabyStepsLabel        matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        ApproachingLabel               matlab.ui.control.Label
        Step1PositionYourselfLabel     matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        IdealKickTutorial2KickingTheBallTab  matlab.ui.container.Tab
        Image8_2                       matlab.ui.control.Image
        Step5ImpacttheballwiththeknuckleofyourbigtoeLabel  matlab.ui.control.Label
        Step4BringYourLegForwardLabel  matlab.ui.control.Label
        Image7_3                       matlab.ui.control.Image
        Step3OrientYourToeTowardsTheGroundLabel  matlab.ui.control.Label
        Image6_3                       matlab.ui.control.Image
        Step2BringYourLegBackLabel     matlab.ui.control.Label
        Image5_3                       matlab.ui.control.Image
        KickingTheBallLabel_2          matlab.ui.control.Label
        Step1RelaxYourBodyLabel        matlab.ui.control.Label
        Image4_3                       matlab.ui.control.Image
        IdealKickTutorialFollowThroughTab  matlab.ui.container.Tab
        Step2PutyourWeightonthefootthatkickedLabel  matlab.ui.control.Label
        Image6_2                       matlab.ui.control.Image
        Step3FollowTheShotLabel        matlab.ui.control.Label
        Image5_2                       matlab.ui.control.Image
        KickingTheBallLabel            matlab.ui.control.Label
        Step1CompleteTheShotLabel      matlab.ui.control.Label
        Image4_2                       matlab.ui.control.Image
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Property % Description
          DataTable % New property for the DataTable
          Weight double 
          LoadedData
          ActualData
          TheoreticalData
          %Session drop down menu
          %kick number item adjusts based on csv file name 
          %Statistical Data (std dev, mean based on one session and then
          %compilation of seperate session
          %ideal kick to compare with 3D flow path plot
          %
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Open file dialog to select a file
            [filename, filepath] = uigetfile({'*.xlsx;*.xls;*.csv', 'Excel Files (*.xlsx, *.xls, *.csv)'; '*.*', 'All Files (*.*)'}, 'Select a File');

            % Check if a file was selected
            if isequal(filename,0) || isequal(filepath,0)
                % User canceled selection
                return;
            end

            % Get the full path of the selected file
            selectedFile = fullfile(filepath, filename);

            % Load the selected file
            data = readmatrix(selectedFile);
            app.LoadedData = data;

            % Check if DataTable exists
            if ~isfield(app, 'DataTable') || isempty(app.DataTable) || ~isvalid(app.DataTable)
                % Create a new DataTable
                app.DataTable = uitable(app.Table);
                app.DataTable.ColumnName = {'Time', 'Acceleration X', 'Acceleration Y', 'Acceleration Z', 'Angular Velocity X', 'Angular Velocity Y', 'Angular Velocity Z'};
                app.DataTable.RowName = {};
                app.DataTable.Position = [12 22 616 412];
            end

            % Update the DataTable with the loaded data
            app.DataTable.Data = data;
            

            % % Check if the data table for the statistical analyses exists
            % if ~isfield(app, 'DataTable1') || isempty(app.DataTable1) || ~isvalid(app.DataTable1)
            %     % Create a new DataTable
            %     app.DataTable1 = uitable(app.StatisticalAnalysesandFeedbackTab);
            %     app.DataTable1.ColumnName = {'Result', 'Value', 'Explanation'}; % Set column names
            %     app.DataTable1.RowName = {}; % Clear row names
            %     app.DataTable1.Position = [12 22 616 412];
            % end
            % 
            % % Get the actual data and theoretical data from your app
            % actual_data = data; % Assuming you have a property named data
            % theoretical_data = app.TheoreticalData; % Assuming you have a property named TheoreticalData
            % 
            % % Calculate Mean of actual data
            % mean_actual = mean(actual_data);
            % 
            % % Calculate Standard Deviation of actual data
            % std_actual = std(actual_data, 0, 1); % Use 0 for sample std, 1 for column-wise std
            % 
            % % Calculate RMSE
            % rmse = sqrt(mean((theoretical_data - actual_data).^2));
            % 
            % % Populate the table with the results and explanations
            % result_names = {'Mean Actual', 'Standard Deviation Actual', 'RMSE'};
            % result_values = {mean_actual, std_actual, rmse};
            % explanations = {'Average value of each column in actual data', ...
            %     'Measure of the spread of values in actual data', ...
            %     'Root Mean Squared Error between theoretical and actual data'};
            % 
            % % Combine data into a cell array
            % table_data = [result_names.', num2cell(result_values.'), explanations.'];
            % 
            % % Update the DataTable1 with the new data
            % app.DataTable1.Data = table_data;


        end

        % Callback function: PlotButton, UIAxes
        function PlotButtonPushed(app, event)
            
            % Check if data is loaded
            if isempty(app.LoadedData)
                % Data is not loaded, display an error message or handle accordingly
                disp('Error: No data loaded. Please load data before plotting.');
                return;
            end

            % Get the loaded data
            data = app.LoadedData;
            
            % % Step 1: Read the CSV file using readmatrix (Assuming no header row)
            % [filename, filepath] = uigetfile({'*.xlsx;*.xls;*.csv', 'Excel Files (*.xlsx, *.xls, *.csv)'; '*.*', 'All Files (*.*)'}, 'Select a File');
            % 
            % % Check if a file was selected
            % if isequal(filename,0) || isequal(filepath,0)
            %     % User canceled selection
            %     return;
            % end
            % 
            % % Get the full path of the selected file
            % selectedFile = fullfile(filepath, filename);
            % 
            % % Load the selected file
            % imu_data = readmatrix(selectedFile);
            % app.LoadedData = data;

            imu_data = data;

            % Step 2: Extract relevant data
            time = imu_data(5:end, 1);  % Extracting time as a vector
            acceleration = imu_data(5:end, 2:4);  % Assuming acceleration is in columns 2, 3, and 4 (x, y, z)
            angular_velocity = imu_data(5:end, 5:7);  % Assuming angular velocity is in columns 5, 6, and 7 (x, y, z)

            % Step 3: Create timeseries objects
            acceleration_ts = timeseries(acceleration, time);
            angular_velocity_ts = timeseries(angular_velocity, time);

            % Step 4: Inverse Kinematics - Integration
            velocity = cumtrapz(time, acceleration);  % Integrate acceleration to get velocity
            weight_lbs = app.WeightEditField.Value;
            mass = weight_lbs / 2.20462262; % Convert lbs to kg
            force = acceleration * mass;  
            % Step 5: Plot the graphs
            % Plot Acceleration
            plot(app.AccelerationAxes, acceleration_ts.Time, acceleration_ts.Data);
            title(app.AccelerationAxes, 'Acceleration');
            xlabel(app.AccelerationAxes, 'Time');
            ylabel(app.AccelerationAxes, 'Acceleration (m/s^2)');
            legend(app.AccelerationAxes, {'X', 'Y', 'Z'});

            % Plot Angular Velocity
            plot(app.AngVelAxes,  angular_velocity_ts.Time,  angular_velocity_ts.Data);
            title(app.AngVelAxes, 'Angular Velocity');
            xlabel(app.AngVelAxes, 'Time');
            ylabel(app.AngVelAxes, 'Angular Velocity (rad/sec)');
            legend(app.AngVelAxes, {'X', 'Y', 'Z'});


            % Plot Force components
            plot(app.ForceAxes, time, force(:, 1), time, force(:, 2), time, force(:, 3), 'LineWidth', 1.5);
            title(app.ForceAxes, 'Force Components vs Time');
            xlabel(app.ForceAxes, 'Time');
            ylabel(app.ForceAxes, 'Force (N)');
            legend(app.ForceAxes, 'X Force', 'Y Force', 'Z Force');

            % Calculate magnitude of force vector
            force_magnitude = sqrt(sum(force.^2, 2)); % Magnitude of force for each time point
            max_force = max(force_magnitude); % Overall max force

            % Update the ForceonImpactEditField with the max force
            app.ForceonImpactEditField.Value = max_force;

            % Plot Velocity components
            plot(app.VelocityAxes, time, velocity(:, 1), time, velocity(:, 2), time, velocity(:, 3), 'LineWidth', 1.5);
            title(app.VelocityAxes, 'Velocity Components vs Time');
            xlabel(app.VelocityAxes, 'Time');
            ylabel(app.VelocityAxes, 'Velocity (m/s)');
            legend(app.VelocityAxes, 'X Velocity', 'Y Velocity', 'Z Velocity');

            % Calculate magnitude of force vector
            velocity_magnitude = sqrt(sum(velocity.^2, 2)); % Magnitude of force for each time point
            max_velocity = max(velocity_magnitude); % Overall max force

            % Update the ForceonImpactEditField with the max force
            app.VelocityonImpactEditField.Value = max_velocity;

            % % Plot Force as 3D vectors
            % quiver3(app.ForceAxes, zeros(size(force, 1), 1), zeros(size(force, 1), 1), zeros(size(force, 1), 1), ...
            %     force(:, 1), force(:, 2), force(:, 3), 'LineWidth', 1.5);
            % title(app.ForceAxes, 'Force in 3D');
            % xlabel(app.ForceAxes, 'X Force (N)');
            % ylabel(app.ForceAxes, 'Y Force (N)');
            % zlabel(app.ForceAxes, 'Z Force (N)');
            % Plot Velocity
            % quiver3(app.VelocityAxes, zeros(size(Velocity, 1), 1), zeros(size(Velocity, 1), 1), zeros(size(Velocity, 1), 1), ...
            %     Velocity(:, 1), Velocity(:, 2), Velocity(:, 3), 'LineWidth', 1.5);
            % title(app.VelocityAxes, 'Velocity in 3D');
            % xlabel(app.VelocityAxes, 'X Velocity (N)');
            % ylabel(app.VelocityAxes, 'Y Velocity (N)');
            % zlabel(app.VelocityAxes, 'Z Velocity (N)');
            % Assuming you have acceleration data stored in 'acceleration' variable
            % Plot Acceleration components


            % Calculate displacement in X, Y, Z directions
            dt = mean(diff(time));  % Assuming uniform time steps
            velocity = cumtrapz(time, acceleration);  % Integrate acceleration to get velocity
            displacement = cumtrapz(time, velocity);  % Integrate velocity to get displacement

            % Extract X, Y, Z components
            displacement_X = displacement(:, 1);
            displacement_Y = displacement(:, 2);
            displacement_Z = displacement(:, 3);

            % Plot 3D displacement on app.UIAxes
            plot3(app.UIAxes, displacement_X, displacement_Y, displacement_Z, 'LineWidth', 1.5);
            title(app.UIAxes, '3D Displacement Plot');
            xlabel(app.UIAxes, 'Displacement X (m)');
            ylabel(app.UIAxes, 'Displacement Y (m)');
            zlabel(app.UIAxes, 'Displacement Z (m)');
            grid(app.UIAxes, 'on');

            
            % 
            % acceleration_X = acceleration(:, 1);
            % acceleration_Y = acceleration(:, 2);
            % acceleration_Z = acceleration(:, 3);
            % plot3(app.AccelerationAxes3D, acceleration_X, acceleration_Y, acceleration_Z, 'LineWidth', 1.5);
            % title(app.AccelerationAxes3D, 'Acceleration Components vs Time');
            % xlabel(app.AccelerationAxes3D, 'Acceleration_X');
            % ylabel(app.AccelerationAxes3D, 'Acceleration_Y');
            % zlabel(app.AccelerationAxes3D, 'Acceleration_Z');
            % grid(app.AccelerationAxes3D, 'on');
            % legend(app.AccelerationAxes3D, {'X', 'Y', 'Z'});
            % 
            % % Plot Angular Velocity components
            % plot3(app.AngVelAxes3D, time, angular_velocity(:, 1), angular_velocity(:, 2), angular_velocity(:, 3), 'LineWidth', 1.5);
            % title(app.AngVelAxes3D, 'Angular Velocity Components vs Time');
            % xlabel(app.AngVelAxes3D, 'Time');
            % ylabel(app.AngVelAxes3D, 'Angular Velocity');
            % zlabel(app.AngVelAxes3D, 'Angular Velocity');
            % legend(app.AngVelAxes3D, {'X', 'Y', 'Z'});
            % 
            % % Plot Force components
            % plot3(app.ForceAxes3D, time, force(:, 1), force(:, 2), force(:, 3), 'LineWidth', 1.5);
            % title(app.ForceAxes3D, 'Force Components vs Time');
            % xlabel(app.ForceAxes3D, 'Time');
            % ylabel(app.ForceAxes3D, 'Force');
            % zlabel(app.ForceAxes3D, 'Force');
            % legend(app.ForceAxes3D, 'X Force', 'Y Force', 'Z Force');
            % 
            % % Plot Velocity components
            % plot3(app.AccelerationAxes3D, time, velocity(:, 1), velocity(:, 2), velocity(:, 3), 'LineWidth', 1.5);
            % title(app.AccelerationAxes3D, 'Velocity Components vs Time');
            % xlabel(app.AccelerationAxes3D, 'Time');
            % ylabel(app.AccelerationAxes3D, 'Velocity');
            % zlabel(app.AccelerationAxes3D, 'Velocity');
            % legend(app.AccelerationAxes3D, 'X Velocity', 'Y Velocity', 'Z Velocity');
            % 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainMenuTab = uitab(app.TabGroup);
            app.MainMenuTab.Title = 'Main Menu ';
            app.Image3 = uiimage(app.MainMenuTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Soccer1.png');
            app.ForceonImpactEditField_2Label = uilabel(app.MainMenuTab);
            app.ForceonImpactEditField_2Label.Text = 'Force on Impact';
            app.ForceonImpactEditField = uieditfield(app.MainMenuTab, 'numeric');
            app.VelocityonImpactEditField_2Label = uilabel(app.MainMenuTab);
            app.VelocityonImpactEditField_2Label.Text = 'Velocity on Impact';
            app.VelocityonImpactEditField = uieditfield(app.MainMenuTab, 'numeric');
            app.WeightEditField_2Label = uilabel(app.MainMenuTab);
            app.WeightEditField_2Label.Text = 'Weight';
            app.WeightEditField = uieditfield(app.MainMenuTab, 'numeric');
            app.WeightEditField.Value = 170;
            app.lbsLabel = uilabel(app.MainMenuTab);
            app.lbsLabel.Text = 'lbs';
            app.NLabel = uilabel(app.MainMenuTab);
            app.NLabel.Text = 'N';
            app.msLabel = uilabel(app.MainMenuTab);
            app.msLabel.Text = 'm/s';
            app.Image2 = uiimage(app.MainMenuTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'gif_over.gif');
            app.PleaseInputyourweightandLoadthesessionFileThankYouLabel = uilabel(app.MainMenuTab);
            app.PleaseInputyourweightandLoadthesessionFileThankYouLabel.Text = 'Please Input your weight and Load the session File Then Plot the data . Thank You!';
            app.LoadButton = uibutton(app.MainMenuTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotButton = uibutton(app.MainMenuTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Hyperlink = uihyperlink(app.MainMenuTab);
            app.Hyperlink.URL = 'https://www.youtube.com/watch?v=od-dwl1QCbQ&t=1s';
            app.Hyperlink.Text = ['Youtube for Ideal Penalty Kick Tutorial' newline ''];
            app.DPlotsTab_2 = uitab(app.TabGroup);
            app.DPlotsTab_2.Title = '2D Plots';
            app.AccelerationAxes = uiaxes(app.DPlotsTab_2);
            title(app.AccelerationAxes, 'Acceleration Vs. Time')
            xlabel(app.AccelerationAxes, 'Time')
            ylabel(app.AccelerationAxes, 'Acceleration')
            zlabel(app.AccelerationAxes, 'Z')
            app.ForceAxes = uiaxes(app.DPlotsTab_2);
            title(app.ForceAxes, 'Force Vs. Time ')
            xlabel(app.ForceAxes, 'Time')
            ylabel(app.ForceAxes, 'Force')
            zlabel(app.ForceAxes, 'Z')
            app.VelocityAxes = uiaxes(app.DPlotsTab_2);
            title(app.VelocityAxes, 'Velocity vs. Time ')
            xlabel(app.VelocityAxes, 'Time')
            ylabel(app.VelocityAxes, 'Velocity')
            zlabel(app.VelocityAxes, 'Z')
            app.AngVelAxes = uiaxes(app.DPlotsTab_2);
            title(app.AngVelAxes, 'Angular Velocity Vs. Time')
            xlabel(app.AngVelAxes, 'Time')
            ylabel(app.AngVelAxes, 'Angular Velocity')
            zlabel(app.AngVelAxes, 'Z')
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.DIdealLegDisplacementTab = uitab(app.TabGroup);
            app.DIdealLegDisplacementTab.Title = '3D Ideal Leg Displacement';
            app.UIAxes = uiaxes(app.DIdealLegDisplacementTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.StatisticalAnalysesandFeedbackTab = uitab(app.TabGroup);
            app.StatisticalAnalysesandFeedbackTab.Title = 'Statistical Analyses and Feedback';
            app.IdealKickTutorial1ApproachingTab = uitab(app.TabGroup);
            app.IdealKickTutorial1ApproachingTab.Title = 'Ideal Kick Tutorial 1:Approaching';
            app.Image4 = uiimage(app.IdealKickTutorial1ApproachingTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-1-Version-5.jpg.jpg');
            app.Step1PositionYourselfLabel = uilabel(app.IdealKickTutorial1ApproachingTab);
            app.Step1PositionYourselfLabel.Text = 'Step 1. Position Yourself';
            app.ApproachingLabel = uilabel(app.IdealKickTutorial1ApproachingTab);
            app.ApproachingLabel.Text = 'Approaching';
            app.Image5 = uiimage(app.IdealKickTutorial1ApproachingTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-2-Version-4.jpg.jpg');
            app.Step2TakeBabyStepsLabel = uilabel(app.IdealKickTutorial1ApproachingTab);
            app.Step2TakeBabyStepsLabel.Text = 'Step 2. Take Baby Steps';
            app.Image6 = uiimage(app.IdealKickTutorial1ApproachingTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-3-Version-3.jpg.jpg');
            app.Step3PlaceyourotherfootclosetotheballLabel = uilabel(app.IdealKickTutorial1ApproachingTab);
            app.Step3PlaceyourotherfootclosetotheballLabel.Text = 'Step 3. Place your other foot close to the ball';
            app.Image7 = uiimage(app.IdealKickTutorial1ApproachingTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-4-Version-4.jpg.jpg');
            app.Step4PointyourfootwhereyouwanttheballtogoLabel = uilabel(app.IdealKickTutorial1ApproachingTab);
            app.Step4PointyourfootwhereyouwanttheballtogoLabel.Text = 'Step 4. Point your foot where you want the ball to go.';
            app.Step5LookattheballbeforehittingitLabel = uilabel(app.IdealKickTutorial1ApproachingTab);
            app.Step5LookattheballbeforehittingitLabel.Text = 'Step 5. Look at the ball before hitting it';
            app.IdealKickTutorial2KickingTheBallTab = uitab(app.TabGroup);
            app.IdealKickTutorial2KickingTheBallTab.Title = 'Ideal Kick Tutorial 2: Kicking The Ball';
            app.Image4_3 = uiimage(app.IdealKickTutorial2KickingTheBallTab);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-6-Version-4.jpg.jpg');
            app.Step1RelaxYourBodyLabel = uilabel(app.IdealKickTutorial2KickingTheBallTab);
            app.Step1RelaxYourBodyLabel.Text = 'Step 1. Relax Your Body';
            app.KickingTheBallLabel_2 = uilabel(app.IdealKickTutorial2KickingTheBallTab);
            app.KickingTheBallLabel_2.Text = 'Kicking The Ball';
            app.Image5_3 = uiimage(app.IdealKickTutorial2KickingTheBallTab);
            app.Image5_3.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-7-Version-2.jpg.jpg');
            app.Step2BringYourLegBackLabel = uilabel(app.IdealKickTutorial2KickingTheBallTab);
            app.Step2BringYourLegBackLabel.Text = 'Step 2. Bring Your Leg Back';
            app.Image6_3 = uiimage(app.IdealKickTutorial2KickingTheBallTab);
            app.Image6_3.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-8-Version-3.jpg.jpg');
            app.Step3OrientYourToeTowardsTheGroundLabel = uilabel(app.IdealKickTutorial2KickingTheBallTab);
            app.Step3OrientYourToeTowardsTheGroundLabel.Text = 'Step 3. Orient Your Toe Towards The Ground';
            app.Image7_3 = uiimage(app.IdealKickTutorial2KickingTheBallTab);
            app.Image7_3.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-9-Version-3.jpg.jpg');
            app.Step4BringYourLegForwardLabel = uilabel(app.IdealKickTutorial2KickingTheBallTab);
            app.Step4BringYourLegForwardLabel.Text = 'Step 4. Bring Your Leg Forward';
            app.Step5ImpacttheballwiththeknuckleofyourbigtoeLabel = uilabel(app.IdealKickTutorial2KickingTheBallTab);
            app.Step5ImpacttheballwiththeknuckleofyourbigtoeLabel.Text = 'Step 5. Impact the ball with the knuckle of your big toe';
            app.Image8_2 = uiimage(app.IdealKickTutorial2KickingTheBallTab);
            app.Image8_2.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-10-Version-3.jpg.jpg');
            app.IdealKickTutorialFollowThroughTab = uitab(app.TabGroup);
            app.IdealKickTutorialFollowThroughTab.Title = 'Ideal Kick Tutorial: Follow Through';
            app.Image4_2 = uiimage(app.IdealKickTutorialFollowThroughTab);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-11-Version-4.jpg.jpg');
            app.Step1CompleteTheShotLabel = uilabel(app.IdealKickTutorialFollowThroughTab);
            app.Step1CompleteTheShotLabel.Text = 'Step 1. Complete The Shot';
            app.KickingTheBallLabel = uilabel(app.IdealKickTutorialFollowThroughTab);
            app.KickingTheBallLabel.Text = 'Kicking The Ball';
            app.Image5_2 = uiimage(app.IdealKickTutorialFollowThroughTab);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-13-Version-3.jpg.jpg');
            app.Step3FollowTheShotLabel = uilabel(app.IdealKickTutorialFollowThroughTab);
            app.Step3FollowTheShotLabel.Text = 'Step 3. Follow The Shot';
            app.Image6_2 = uiimage(app.IdealKickTutorialFollowThroughTab);
            app.Image6_2.ImageSource = fullfile(pathToMLAPP, 'Ideal Kick', 'v4-728px-Kick-a-Soccer-Ball-Hard-Step-12-Version-2.jpg.jpg');
            app.Step2PutyourWeightonthefootthatkickedLabel = uilabel(app.IdealKickTutorialFollowThroughTab);
            app.Step2PutyourWeightonthefootthatkickedLabel.Text = 'Step 2. Put your Weight on the foot that kicked';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.MainMenuTab.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end